classdef iosgui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        spatialbinning                  matlab.ui.control.Button
        SpatialBinningEditField         matlab.ui.control.NumericEditField
        SpatialBinningEditFieldLabel    matlab.ui.control.Label
        AdjustContrastButton            matlab.ui.control.Button
        ProgramMessagesLabel            matlab.ui.control.Label
        MsgArea                         matlab.ui.control.TextArea
        TrialLengthEditField            matlab.ui.control.NumericEditField
        TrialLengthEditFieldLabel       matlab.ui.control.Label
        TrialLength                     matlab.ui.control.Button
        importvasc                      matlab.ui.control.Button
        percentthreshold                matlab.ui.control.Button
        zscoreoverlay                   matlab.ui.control.Button
        processfiles                    matlab.ui.control.Button
        confirmmask                     matlab.ui.control.Button
        importfile                      matlab.ui.control.Button
        DisplayedImagesLabel            matlab.ui.control.Label
        FileSelectionLabel              matlab.ui.control.Label
        ImageAcquisitionSettingsLabel   matlab.ui.control.Label
        AddMaskButton                   matlab.ui.control.Button
        baselineperiod                  matlab.ui.control.Button
        temporalbinning                 matlab.ui.control.Button
        stimoffset                      matlab.ui.control.Button
        instrinsicsignal                matlab.ui.control.Button
        numbertrials                    matlab.ui.control.Button
        AbsoluteNumber                  matlab.ui.control.NumericEditField
        generatepercentthreshholdoverlay  matlab.ui.control.Button
        StimulusOffsetEditField         matlab.ui.control.NumericEditField
        StimulusOffsetEditFieldLabel    matlab.ui.control.Label
        generatezscorethreshholdoverlay  matlab.ui.control.Button
        ThresholdNumber                 matlab.ui.control.NumericEditField
        ImportVasculatureImageButton    matlab.ui.control.Button
        ProcessFilesButton              matlab.ui.control.Button
        BaselinePeriodEditField         matlab.ui.control.NumericEditField
        BaselinePeriodEditFieldLabel    matlab.ui.control.Label
        TemporalBinningEditField        matlab.ui.control.NumericEditField
        TemporalBinningEditFieldLabel   matlab.ui.control.Label
        IntrinsicSignalPeriodEditField  matlab.ui.control.NumericEditField
        IntrinsicSignalPeriodEditFieldLabel  matlab.ui.control.Label
        NumberofTrialsEditField         matlab.ui.control.NumericEditField
        NumberofTrialsEditFieldLabel    matlab.ui.control.Label
        ImportImagingFilesButton        matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

  
    properties (Access = private)
       dR_all
       dR_Avg
       numbins
       numframes
       movlength
       movlength1
       baseSum
       s
       stimSum
       cutvalue
       imgfiles
       imgpath
       common_to_use
       dfw
       invert
       circ
       mask
       v
       cut
       z
       f
       w
       h
       finalimage
       path_xml
       file_xml
       name_file
       C
       tmax
       tmin
       thorimage
       ISIimage
       t
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportImagingFilesButton
        function ImportImagingFilesButtonPushed(app, event)
        %This button will generate a list of imaging files to be used in subsequent
        %steps for image processing. Currently the app can handle
        %individual tif files that are consecutively numbered or a tif
        %stack.
        
            %Get image files
            [app.imgfiles,app.imgpath]=uigetfile('*.tif','Select the INPUT DATA FILE(s)','MultiSelect','on');
            %Set the current directory to the folder containing image files
            cd(app.imgpath)
            
            %Find a base name common to all imaging files so files can
            %later be iteratively called and loaded
            if iscell(app.imgfiles)==1
                app.imgfiles=app.imgfiles';
                %Find the characters common to all image files
                Schar = char(app.imgfiles(:)); %convert cells to character matrix
                all_rows_same = any(diff(Schar,1,1)); %Find any row where characters don't all match
                common_cols = find(all_rows_same, 1, 'first')-1; %Subtract 1 to get end of all common characters
                if isempty(common_cols)
                app.common_to_use = '?';
                else
                app.common_to_use = app.imgfiles{1}(1:common_cols); %Get the base name common to all images
                end
                %Find the length of the digit span used for numbering images
                app.dfw=length(app.imgfiles{1})-length(app.common_to_use)-4; %subtract 4 for .tif
            else
                app.common_to_use = app.imgfiles; %Use the full file name for a tif stack
            end
                
            
            %Check that expected #files matches input files and display a
            %warning if not
            expectedfilenum=app.NumberofTrialsEditField.Value*app.TrialLengthEditField.Value;
            if iscell(app.imgfiles)==1 %for individually loaded images
                importedfilenum=length(app.imgfiles);
                if expectedfilenum~=importedfilenum
                    app.MsgArea.Value=...
                        sprintf('Expected %d images based on number of trials and trial length, but %d images loaded. Check to make sure correct files were selected.',...
                        expectedfilenum,importedfilenum);
                else
                    app.MsgArea.Value=sprintf('%d Images Added Successfully',importedfilenum);
                end
            else %for tif stacks
                imginfo=imfinfo(app.imgfiles); 
                if imginfo(1).FileSize>4e+9 %imginfo won't load data for all images if file size > 4 GB
                    app.MsgArea.Value=sprintf('File size too large for automatic error checking. OK to proceed if ''%s'' is the correct file. Press the ''Process Files'' button when ready to proceed.',...
                        app.imgfiles);
                else
                    importedfilenum=length(imfinfo(app.imgfiles));
                    if expectedfilenum~=importedfilenum
                        app.MsgArea.Value=...
                            sprintf('Expected %d images based on number of trials and trial length, but %d images loaded. Check to make sure correct files were selected.',...
                            expectedfilenum,importedfilenum);
                    else
                        app.MsgArea.Value=sprintf('%d Images Added Successfully. Press the ''Process Files'' button when ready to proceed.',importedfilenum);
                    end
                end
            end
            
        end

        % Button pushed function: ProcessFilesButton
        function ProcessFilesButtonPushed(app, event)
            %Run IOSGUI_ImageAnalysis(numtrials,totframes,baseframes,iosframes,
            % stimoffset,tempbin,spatialbin,imgbasename,dfw,numfiles)
            
            %Will calculate dR/R values for each pixel and generate an
            %image of those values, scaled to the minimum and maximum dR/R
            %values
            
            app.MsgArea.Value=sprintf('Processing Images...');
            [app.ISIimage,scaledfilename]=IOSGUI_ImageAnalysis(app.NumberofTrialsEditField.Value,...
                app.TrialLengthEditField.Value,app.BaselinePeriodEditField.Value,...
                app.IntrinsicSignalPeriodEditField.Value,...
                app.StimulusOffsetEditField.Value,app.TemporalBinningEditField.Value,...
                app.SpatialBinningEditField.Value,...
                app.common_to_use,app.dfw,size(app.imgfiles,1));
    
            %Display the scaled image
            cla(app.UIAxes); %clear any previously displayed images
            imshow(scaledfilename, 'Parent', app.UIAxes);
            app.MsgArea.Value=sprintf('Images processed successfully. Displayed scaled \x0394R/R image. Press the ''Import Vasculature Image'' button if you would like to generate an overlay image.');

        end

        % Button pushed function: ImportVasculatureImageButton
        function ImportVasculatureImageButtonPushed(app, event)
            %% Get & display a vasculature image
            [vascFile,vascFold] = uigetfile('*.tif');              
            app.v=imread(sprintf('%s/%s',vascFold,vascFile));
            app.v=imresize(app.v,app.SpatialBinningEditField.Value,'bilinear'); %perform spatial binning
            [app.h, app.w]=size(app.v); %Get image size
            cla(app.UIAxes); %Clear any previously displayed images
            %Adjust image position a bit to center better
            OP=app.UIAxes.OuterPosition;
            imshow(app.v,'Parent', app.UIAxes);
            IP=app.UIAxes.InnerPosition;
            dP=OP-IP;
            app.UIAxes.InnerPosition=[(OP(1)+dP(3)) (OP(2)+dP(4)) IP(3) IP(4)];
            app.MsgArea.Value=sprintf('Vasculature image ''%s'' added. Press the ''Add Mask'' button to mask areas of the image or proceed to the ''Generate Overlay'' buttons.',vascFile);
            %Clear out any prior masks that were created
            if ~isempty(app.mask)
               app.mask=[];
            else
            end

        end

        % Button pushed function: AddMaskButton
        function AddMaskButtonPushed(app, event)
            %Add an ellipse to the vasculature image that can be resized to
            %create a mask for generating overlay images
            
            %Add the mask and store it as a variable
            app.circ = drawellipse(app.UIAxes,'Center',[app.w/2, app.h/2],'SemiAxes',[app.w/2-50, app.h/2-20]); 
            app.mask = createMask(app.circ); 
            app.MsgArea.Value=sprintf('Mask added. Update the ellipse as needed then proceed to the ''Generate Overlay'' buttons.');
            
            %Create a listener to update the mask variable if the mask is
            %resized
            addlistener(app.circ,'ROIMoved',@circlemoved);
            function circlemoved(src,evt)
                app.mask = createMask(app.circ);
                app.MsgArea.Value=sprintf('Mask updated. Once finalized, proceed to the ''Generate Overlay'' buttons.');
            end
            
        end

        % Button pushed function: generatezscorethreshholdoverlay
        function generatezscorethreshholdoverlayButtonPushed(app, event)
            %This button will Z-score pixels from the dR/R image (excluding
            %pixels outside any mask that has been applied), create a
            %binary image based on the Z-score threshold, pseudocolor that
            %binary image, and overlay it onto the imported vasculature
            %image.
            
            app.MsgArea.Value=sprintf('Generating overlay...');
            
            %Check to make sure image processing has already been performed
            if isempty(app.ISIimage)
                app.MsgArea.Value=sprintf('Intrinsic signal images not yet processed. Run process files and try again.');
                return
            else
            end
            
            %Check for a mask. If one was not created, set mask to 1
            %(meaning no mask will be applied)
            if isempty(app.mask)
               app.mask=1;
            else
            end
            
            %Set pixels outside the mask area to NaN, calculate Z-scores,
            %and generate binary image
            app.mask=double(app.mask); %convert logical to double
            app.mask(app.mask==0)=NaN; %convert 0s to NaN
            app.z=app.mask.*app.ISIimage; %Set pixels outside mask to Nan
            app.z=(app.z-mean(app.z,'all','omitnan'))/std(app.z,0,'all','omitnan'); %Calculate Z-score
            app.cut=app.ThresholdNumber.Value; %Get the Z-score threshould
            thresh=app.z;
            thresh(thresh>app.cut)=1; %We want negative numbers, so set pixels above threshold to 1
            thresh(thresh<1)=0; %Set remaining pixels to 0
            thresh(isnan(thresh))=1; %Set masked pixels to 1
            app.invert=~thresh; %Now we have a binary image with ISI signals equal to 1
            app.invert=rescale(app.invert,0, 255); %rescale to convert to 8-bit image
            app.invert=uint8(app.invert); %convert to 8-bit indexed image
            
            %Check to see if another binary image with the same threshold
            %already exists. If it does, update the filename to include the
            %date so the file isn't overwritten. Then save the image.
            binaryfilename=sprintf('Binary_Z_%.2f.tif',app.cut); %Create a name to save the image
            if exist(binaryfilename,'file') 
                binaryfilename=sprintf('Binary_Z_%.2f_%s.tif',app.cut,string(datetime,'MMddyyyy_HHmmss'));
                imwrite(app.invert,binaryfilename);
            else
                imwrite(app.invert,binaryfilename);
            end
            
            %Create the overlay image and display it
            app.invert=ind2rgb(app.invert,[0 0 0; 1 0 0]); %convert to RGB with black/red binary colormap 
            app.C=imfuse(app.v,app.invert,'blend'); %Combine with vasculature image
            cla(app.UIAxes); %Clear any previously displayed images
            imshow(app.C, 'Parent', app.UIAxes); %Display the overlay image
            
            %Check to see if another overlay image with the same threshold
            %already exists. If it does, update the filename to include the
            %date so the file isn't overwritten. Then save the image.
            overlayfilename=sprintf('Overlay_Z_%.2f.tif',app.cut);
            if exist(overlayfilename,'file')
                overlayfilename=sprintf('Overlay_Z_%.2f_%s.tif',app.cut,string(datetime,'MMddyyyy_HHmmss'));
                imwrite(app.C,overlayfilename);
            else
                imwrite(app.C,overlayfilename);
            end
            
            app.MsgArea.Value=sprintf('Overlay using Z-score threshold of %.2f applied. Image saved.',...
                app.cut);
        end

        % Button pushed function: generatepercentthreshholdoverlay
        function generatepercentthreshholdoverlayButtonPushed(app, event)
            %This button will find the range of pixel intensities from the dR/R image (excluding
            %pixels outside any mask that has been applied), create a
            %binary image based on the percentage maximum pixel intensity threshold, pseudocolor that
            %binary image, and overlay it onto the imported vasculature
            %image.
            
            app.MsgArea.Value=sprintf('Generating overlay...');
            
            %Check to make sure image processing has already been performed
            if isempty(app.ISIimage)
                app.MsgArea.Value=sprintf('Intrinsic signal images not yet processed. Run process files and try again.');
                return
            else
            end
            
            %Check for a mask. If one was not created, set mask to 1
            %(meaning no mask will be applied)
            if isempty(app.mask)
               app.mask=1;
            else
            end
            
            %Set pixels outside the mask area to NaN, calculate max/min range,
            %and generate binary image
            app.mask=double(app.mask); %convert logical to double
            app.mask(app.mask==0)=NaN; %convert 0s to NaN
            app.t=app.mask.*app.ISIimage; %Set pixels outside mask area to NaN
            app.tmax=max(app.t,[],'all'); %Calculate max pixel intensity
            app.tmin=min(app.t,[],'all'); %Calculate min pixel intensity
            app.t=app.t-app.tmin; %Adjust pixel values to be positive
            app.cut=(app.AbsoluteNumber.Value/100)*max(app.t,[],'all'); %Determine the threshold value as a % of max pixel intensity
            thresh=app.t;
            thresh(thresh<app.cut)=0; %Set values below threshold (signal) to 0
            thresh(thresh>0)=1; %Set other pixels to 1
            thresh(isnan(thresh))=1; %Set masked pixels to 1
            app.invert=~thresh; %Now we have a binary image with ISI signals equal to 1
            app.invert=rescale(app.invert,0, 255); %rescale to convert to 8-bit image
            app.invert=uint8(app.invert); %convert to 8-bit indexed image
            
            %Check to see if another binary image with the same threshold
            %already exists. If it does, update the filename to include the
            %date so the file isn't overwritten. Then save the image.
            binaryfilename=sprintf('Binary_PrctT_%.2f.tif',app.AbsoluteNumber.Value);
            if exist(binaryfilename,'file')
                binaryfilename=sprintf('Binary_PrctT_%.2f_%s.tif',app.AbsoluteNumber.Value,string(datetime,'MMddyyyy_HHmmss'));
                imwrite(app.invert,binaryfilename);
            else
                imwrite(app.invert,binaryfilename);
            end
            
            %Create the overlay image and display it
            app.invert=ind2rgb(app.invert,[0 0 0; 1 0 0]); %convert to RGB with black/red binary colormap      
            app.C=imfuse(app.v,app.invert,'blend'); %combine with vasculature image
            cla(app.UIAxes); %clear any previously displayed imaged
            imshow(app.C, 'Parent', app.UIAxes); %display overlay image
            
            %Check to see if another overlay image with the same threshold
            %already exists. If it does, update the filename to include the
            %date so the file isn't overwritten. Then save the image.
            overlayfilename=sprintf('Overlay_PrctT_%.2f.tif',app.AbsoluteNumber.Value);
            if exist(overlayfilename,'file')
                overlayfilename=sprintf('Overlay_PrctT_%.2f_%s.tif',app.AbsoluteNumber.Value,string(datetime,'MMddyyyy_HHmmss'));
                imwrite(app.C,overlayfilename);
            else
                imwrite(app.C,overlayfilename);
            end
            
            app.MsgArea.Value=sprintf('Overlay using top %.2f%% threshold applied. Image saved.',...
                app.AbsoluteNumber.Value);

        end

        % Button pushed function: AdjustContrastButton
        function AdjustContrastButtonPushed(app, event)
            %The image contrast tool in the image processing toolbox works
            %well for grayscale images, but not RGB images. So use the
            %image contrast tool for dR/R images or vasculature images. For
            %RGB images, we can do quick contrast adjustments, but users
            %will probalby need to use dedicated image processing software
            %for more careful adjustments. Any contrast adjustments
            %performed in the app are not saved automatically, but can be
            %saved using the toolbar.
            
            try
                imcontrast(app.UIAxes)
                app.MsgArea.Value=sprintf('Contrast adjustments are for display only. To export, use the save function in the toolbar at the upper right of the image area.');
            catch ME %look for an error which is thrown when trying to use the tool on RGB images
                if (strcmp(ME.identifier,'images:imcontrast:unsupportedImageType'))
                    app.C=imlocalbrighten(app.C,0.05); %brighten the image
                    cla(app.UIAxes); %clear previously displayed images
                    imshow(app.C, 'Parent', app.UIAxes); %display the new image
                    app.MsgArea.Value=sprintf('Contrast adjustments for RGB images are limited and are for display only. To export, use the save function in the toolbar at the upper right of the image area.');
                end
            end
        end

        % Button pushed function: numbertrials
        function numbertrialsButtonPushed(app, event)
            msgbox("Number of complete trials (including baseline and stimulation periods) to be analyzed.");
        end

        % Button pushed function: TrialLength
        function TrialLengthButtonPushed(app, event)
            msgbox("Total number of frames acquired for each trial.")
        end

        % Button pushed function: baselineperiod
        function baselineperiodButtonPushed(app, event)
            msgbox("Length of pre-stimulus baseline period (in frames) in each trial.");
        end

        % Button pushed function: instrinsicsignal
        function instrinsicsignalButtonPushed(app, event)
            msgbox("Number of frames over which to analyze intrinsic signals. Usually equal to 1-1.5 seconds.")
        end

        % Button pushed function: stimoffset
        function stimoffsetButtonPushed(app, event)
            msgbox("Number of frames after stimulus onset to begin analyzing for intrinsic signals. Consider offset equal to 0-0.5 seconds.");
        end

        % Button pushed function: temporalbinning
        function temporalbinningButtonPushed(app, event)
            msgbox("Number of frames over which to bin acquired frames for analysis. Consider binning equal to 300-500 ms.");
        end

        % Button pushed function: spatialbinning
        function spatialbinningButtonPushed(app, event)
           msgbox("Factor for spatial downsampling. Can reduce noise, but also reduces spatial resolution. 1 = no binning. Consider factor of 0.25-0.5 for larger images."); 
        end

        % Button pushed function: importfile
        function importfileButtonPushed(app, event)
            msgbox("Choose all imaging files (*.tif) to be analyzed. Can be a multi-page .tif file or individual images. Individual images should be numbered sequentially and share a common base name (e.g. “Image_001, Image_002, etc.”). Processing will occur faster if images are stored locally or accessible with a high speed data connection.")
        end

        % Button pushed function: processfiles
        function processfilesButtonPushed(app, event)
            msgbox("Calculate intrinsic signals. Will generate a scaled (minimum to maximum) change in reflectance value image.")
        end

        % Button pushed function: importvasc
        function importvascButtonPushed(app, event)
            msgbox("Choose an associated image of vasculature onto which intrinsic signals will be overlaid.")
        end

        % Button pushed function: confirmmask
        function confirmmaskButtonPushed(app, event)
            msgbox("Add a mask to ignore signals outside a defined area. For example, adjust the ellipse to match the area of a cranial window.")
        end

        % Button pushed function: zscoreoverlay
        function zscoreoverlayButtonPushed(app, event)
            msgbox("Calculates Z-scores of change in reflectance values across all pixels, and applies the set threshold to generate a binary image of intrinsic signals below the threshold.")
        end

        % Button pushed function: percentthreshold
        function percentthresholdButtonPushed(app, event)
            msgbox("Set a threshold as the percentage of the maximum change in reflectance value to generate a binary image of intrinsic signals below the threshold.")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Tag = 'axes1';
            app.ImportImagingFilesButton = uibutton(app.UIFigure, 'push');
            app.ImportImagingFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportImagingFilesButtonPushed, true);
            app.ImportImagingFilesButton.Text = 'Import Imaging Files';
            app.NumberofTrialsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofTrialsEditFieldLabel.Text = 'Number of Trials';
            app.NumberofTrialsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofTrialsEditField.Value = 15;
            app.IntrinsicSignalPeriodEditFieldLabel = uilabel(app.UIFigure);
            app.IntrinsicSignalPeriodEditFieldLabel.Text = ' Intrinsic Signal Period';
            app.IntrinsicSignalPeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.IntrinsicSignalPeriodEditField.Value = 25;
            app.TemporalBinningEditFieldLabel = uilabel(app.UIFigure);
            app.TemporalBinningEditFieldLabel.Text = 'Temporal Binning';
            app.TemporalBinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemporalBinningEditField.Value = 3;
            app.BaselinePeriodEditFieldLabel = uilabel(app.UIFigure);
            app.BaselinePeriodEditFieldLabel.Text = 'Baseline Period';
            app.BaselinePeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaselinePeriodEditField.Value = 5;
            app.ProcessFilesButton = uibutton(app.UIFigure, 'push');
            app.ProcessFilesButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessFilesButtonPushed, true);
            app.ProcessFilesButton.Text = 'Process Files';
            app.ImportVasculatureImageButton = uibutton(app.UIFigure, 'push');
            app.ImportVasculatureImageButton.ButtonPushedFcn = createCallbackFcn(app, @ImportVasculatureImageButtonPushed, true);
            app.ImportVasculatureImageButton.Text = 'Import Vasculature Image';
            app.ThresholdNumber = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdNumber.Value = -3;
            app.generatezscorethreshholdoverlay = uibutton(app.UIFigure, 'push');
            app.generatezscorethreshholdoverlay.ButtonPushedFcn = createCallbackFcn(app, @generatezscorethreshholdoverlayButtonPushed, true);
            app.generatezscorethreshholdoverlay.Text = 'Generate Z-Score Overlay';
            app.StimulusOffsetEditFieldLabel = uilabel(app.UIFigure);
            app.StimulusOffsetEditFieldLabel.Text = 'Stimulus Offset';
            app.StimulusOffsetEditField = uieditfield(app.UIFigure, 'numeric');
            app.StimulusOffsetEditField.Value = 0.5;
            app.generatepercentthreshholdoverlay = uibutton(app.UIFigure, 'push');
            app.generatepercentthreshholdoverlay.ButtonPushedFcn = createCallbackFcn(app, @generatepercentthreshholdoverlayButtonPushed, true);
            app.generatepercentthreshholdoverlay.Text = 'Generate % Threshold Overlay';
            app.AbsoluteNumber = uieditfield(app.UIFigure, 'numeric');
            app.AbsoluteNumber.Value = 40;
            app.numbertrials = uibutton(app.UIFigure, 'push');
            app.numbertrials.ButtonPushedFcn = createCallbackFcn(app, @numbertrialsButtonPushed, true);
            app.numbertrials.Text = '?';
            app.instrinsicsignal = uibutton(app.UIFigure, 'push');
            app.instrinsicsignal.ButtonPushedFcn = createCallbackFcn(app, @instrinsicsignalButtonPushed, true);
            app.instrinsicsignal.Text = '?';
            app.stimoffset = uibutton(app.UIFigure, 'push');
            app.stimoffset.ButtonPushedFcn = createCallbackFcn(app, @stimoffsetButtonPushed, true);
            app.stimoffset.Text = '?';
            app.temporalbinning = uibutton(app.UIFigure, 'push');
            app.temporalbinning.ButtonPushedFcn = createCallbackFcn(app, @temporalbinningButtonPushed, true);
            app.temporalbinning.Text = '?';
            app.baselineperiod = uibutton(app.UIFigure, 'push');
            app.baselineperiod.ButtonPushedFcn = createCallbackFcn(app, @baselineperiodButtonPushed, true);
            app.baselineperiod.Text = '?';
            app.AddMaskButton = uibutton(app.UIFigure, 'push');
            app.AddMaskButton.ButtonPushedFcn = createCallbackFcn(app, @AddMaskButtonPushed, true);
            app.AddMaskButton.Text = 'Add Mask';
            app.ImageAcquisitionSettingsLabel = uilabel(app.UIFigure);
            app.ImageAcquisitionSettingsLabel.Text = 'Image Acquisition Settings';
            app.FileSelectionLabel = uilabel(app.UIFigure);
            app.FileSelectionLabel.Text = 'File Selection';
            app.DisplayedImagesLabel = uilabel(app.UIFigure);
            app.DisplayedImagesLabel.Text = 'Displayed Images';
            app.importfile = uibutton(app.UIFigure, 'push');
            app.importfile.ButtonPushedFcn = createCallbackFcn(app, @importfileButtonPushed, true);
            app.importfile.Text = '?';
            app.confirmmask = uibutton(app.UIFigure, 'push');
            app.confirmmask.ButtonPushedFcn = createCallbackFcn(app, @confirmmaskButtonPushed, true);
            app.confirmmask.Text = '?';
            app.processfiles = uibutton(app.UIFigure, 'push');
            app.processfiles.ButtonPushedFcn = createCallbackFcn(app, @processfilesButtonPushed, true);
            app.processfiles.Text = '?';
            app.zscoreoverlay = uibutton(app.UIFigure, 'push');
            app.zscoreoverlay.ButtonPushedFcn = createCallbackFcn(app, @zscoreoverlayButtonPushed, true);
            app.zscoreoverlay.Text = '?';
            app.percentthreshold = uibutton(app.UIFigure, 'push');
            app.percentthreshold.ButtonPushedFcn = createCallbackFcn(app, @percentthresholdButtonPushed, true);
            app.percentthreshold.Text = '?';
            app.importvasc = uibutton(app.UIFigure, 'push');
            app.importvasc.ButtonPushedFcn = createCallbackFcn(app, @importvascButtonPushed, true);
            app.importvasc.Text = '?';
            app.TrialLength = uibutton(app.UIFigure, 'push');
            app.TrialLength.ButtonPushedFcn = createCallbackFcn(app, @TrialLengthButtonPushed, true);
            app.TrialLength.Text = '?';
            app.TrialLengthEditFieldLabel = uilabel(app.UIFigure);
            app.TrialLengthEditFieldLabel.Text = 'Trial Length';
            app.TrialLengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialLengthEditField.Value = 30;
            app.MsgArea = uitextarea(app.UIFigure);
            app.ProgramMessagesLabel = uilabel(app.UIFigure);
            app.ProgramMessagesLabel.Text = 'Program Messages';
            app.AdjustContrastButton = uibutton(app.UIFigure, 'push');
            app.AdjustContrastButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustContrastButtonPushed, true);
            app.AdjustContrastButton.Text = 'Adjust Contrast';
            app.SpatialBinningEditFieldLabel = uilabel(app.UIFigure);
            app.SpatialBinningEditFieldLabel.Text = 'Spatial Binning';
            app.SpatialBinningEditField = uieditfield(app.UIFigure, 'numeric');
            app.SpatialBinningEditField.Value = 0.25;
            app.spatialbinning = uibutton(app.UIFigure, 'push');
            app.spatialbinning.ButtonPushedFcn = createCallbackFcn(app, @spatialbinningButtonPushed, true);
            app.spatialbinning.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = iosgui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end